classdef Calcium_Stan_Single_Neuron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        PlotMenu_2                matlab.ui.container.Menu
        TotalIntensityGCaMPMenu   matlab.ui.container.Menu
        RatiouseaftertotalMenu    matlab.ui.container.Menu
        Signal_temperatureMenu    matlab.ui.container.Menu
        MakeStableMovieMenu       matlab.ui.container.Menu
        NeuronposButton           matlab.ui.control.Button
        SaveSignalButton          matlab.ui.control.Button
        ReferenceSignalButton     matlab.ui.control.Button
        InstructionTextArea       matlab.ui.control.TextArea
        InstructionTextAreaLabel  matlab.ui.control.Label
        TrackingLamp              matlab.ui.control.Lamp
        TrackingLampLabel         matlab.ui.control.Label
        Spinner                   matlab.ui.control.Spinner
        Slider                    matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        ContextMenu               matlab.ui.container.ContextMenu
        FileMenu                  matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        CloseMenu                 matlab.ui.container.Menu
        TrackMenu                 matlab.ui.container.Menu
        LUTMenu                   matlab.ui.container.Menu
        TrackingMenu              matlab.ui.container.Menu
        SearchMenu                matlab.ui.container.Menu
        TresholdMenu              matlab.ui.container.Menu
        PlotMenu                  matlab.ui.container.Menu
        SignalMenu                matlab.ui.container.Menu
        TotalIntensityredMenu     matlab.ui.container.Menu
    end


    properties (Access = private)
        handles % Description
        roi % Description
        signal % Description
    end

    methods (Access = private)

        function post_position = UpdateNeuronPosition(app,img,pre_position,radius,threshold)
            % Function to track neuron position. Uses Search radius specified
            % in the menu to find brightest blob
            if radius ==0
                post_position = pre_position;

            else
                % OLD version
                %                 [height,width]=size(img);
                %                 ymin=max(round(pre_position(2)-radius),1);
                %                 ymax=min(round(pre_position(2)+radius),height);
                %                 xmin=max(round(pre_position(1)-radius),1);
                %                 xmax=min(round(pre_position(1)+radius),width);
                %                 h = fspecial('gaussian',[3,3],3);
                %                 c_filtered=imfilter(img(ymin:ymax,xmin:xmax),h);
                %                 %threshold and find the local maxima
                %                 bw = imextendedmax(c_filtered,threshold);
                %                 L=logical(bw);
                %                 s=regionprops(L,'Centroid');
                %                 %putative neuronal positions
                %                 Centroids=cat(1,s.Centroid);
                %                 [~,idx]=min(sum((Centroids-repmat(pre_position-[xmin,ymin],size(Centroids,1),1)).^2,2));
                %                 post_position=Centroids(idx,:)+[xmin,ymin]; %update neuron positions

                % NEW version

                imagescale = img-app.handles.low;
                imagescale=imagescale*(255/app.handles.high);
                imagescale(imagescale<255/100*threshold)=0;
                se = strel('disk',1);
                Ie = imerode(imagescale,se);
                Iobr = imreconstruct(Ie,imagescale);
                Iobrd = imdilate(Iobr,se);
                fgm = imregionalmax(Iobrd);


                % Use size limitation of radius of the roi to find objects
                cc = bwpropfilt(fgm,'MajorAxisLength',[3 2*app.handles.r(1)],8);
                stats = regionprops(cc,'Centroid');
                Centroids=cat(1,stats.Centroid);
                if isempty(Centroids)
                    post_position = pre_position;
                else
                    [~,idx]=min(sum((Centroids-repmat(pre_position,size(Centroids,1),1)).^2,2));
                    post_position=Centroids(idx,:); %update neuron positions
                end


            end
        end


        function mean_F = CalculateIntensity(app,F)
            % Function to calculate intensity of 90% of pixels in the ROI
            % uses minimal values from roi
            n=0.90;
            F_sorted=sort(F,'descend');
            % weakest 10% of the pixels
            baseline_F=mean(F_sorted(end-floor(n*length(F)):end));
            % 
            output_F= sum(F_sorted(1:floor((1-n)*length(F)))-baseline_F);
            if size(F)<100
                % Get total intensity of brightest half of the pixels
                mean_F = sum(F_sorted(1:floor(length(F)/2)))/floor(length(F)/2);
            else
                % Get total intensity of brightest 10% of the pixels
                mean_F = sum(F_sorted(1:floor(length(F)/10)))/floor(length(F)/10);
            end
            
            
            % if size(F)<100
            %     % Get total intensity of brightest half of the pixels
            %     mean_F = sum(F_sorted(1:floor(length(F)/2)));
            % else
            %     % Get total intensity of brightest 10% of the pixels
            %     mean_F = sum(F_sorted(1:floor(length(F)/10)));
            % end
            
        end

        function c_mask = CircleMask(app,ix,iy,cx,cy,r)
            [x,y]=meshgrid(-(cx-1):(ix-cx),-(cy-1):(iy-cy));
            c_mask=((x.^2+y.^2)<=r^2);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Choose default command line output for proof_reading
            % filename of hdf5
            app.signal.filename = varargin{1};                          
            % max intensity stacks of red and green channel
            image_stacks = varargin{2};                                 
            % Default threshold for neurons intensity (% of max)
            app.handles.tracking_threshold=10;
            % use difference of gaussians to filter red channel
            % gausian1=fspecial('gaussian',15,6);
            % gausian2=fspecial('gaussian',15,7);
            gausian1=fspecial('gaussian',10,6);
            gausian2=fspecial('gaussian',10,7);
            % gausian1=fspecial('gaussian',7,6);
            % gausian2=fspecial('gaussian',7,7);
            dog=gausian1-gausian2;
            dogFilterStack = uint8(zeros(size(image_stacks{1})));
            for i=varargin{5}:varargin{6}
                dogFilterStack(:,:,i) = uint8(conv2(double(image_stacks{1}(:,:,i)),dog,'same')*255);
            end
%             dogFilterStack_max = max(dogFilterStack,[],'all');
%             dogFilterStack(dogFilterStack<dogFilterStack_max*app.handles.tracking_threshold/100) = 0;
            % decide if use difference of Gaussians for red channel if
            % DOG=1 use, if DOG=0 - don't use
            app.handles.DOG = varargin{8};
            if app.handles.DOG==1
                    app.handles.img_stack1=dogFilterStack;
            elseif app.handles.DOG==0
                    app.handles.img_stack1=image_stacks{1}
            end

            app.handles.img_stack2=image_stacks{2};
            % transformation for the image
            app.handles.transformation_array=varargin{3};
            % name from the details of the hdf5
            app.handles.filename=varargin{4};
            % first frame
            app.handles.istart=varargin{5};
            % last frame
            app.handles.iend=varargin{6};
            % time points
            % try using time from the Core's hdf5
            try
                app.handles.image_times=h5read(char(app.signal.filename),'/time');
                app.handles.image_times = app.handles.image_times-app.handles.image_times(1);
            catch
                warning("HDF5 file doesn't contain time")
                app.handles.image_times = varargin{7};
            end

            app.handles.neuron_number = 1;
            app.handles.colorset=[0 0.4470 0.7410;0.8500 0.3250 0.0980;0.9290 0.6940 0.1250;0.4940 0.1840 0.5560;...
                0.4660 0.6740 0.1880;0.3010 0.7450 0.9330;0.6350 0.0780 0.1840;0 0.4470 0.7410;0.8500 0.3250 0.0980;0.9290 0.6940 0.1250;0.4940 0.1840 0.5560];
            app.handles.x_shift=0;
            app.handles.y_shift=0;
            % Get frame dimesnions
            [height,width,sections]=size(app.handles.img_stack1);
            % Build app figures
            app.UIFigure.Position = [400 200 width*2+50 height+200];
            app.UIAxes.Position = [20 50 width height];
            app.UIAxes_2.Position = [width+30 50 width height];
            app.Slider.Position = [20 50 width*2 3];
            app.Spinner.Position = [20 height+100 50 50];
            app.handles.image_width=width;
            app.handles.image_height=height;
            app.handles.image_depth=app.handles.iend-app.handles.istart+1;
            app.handles.sections=sections;
            %initialize neuronal position;
            if length(varargin)==9
                app.handles.neuronal_position=varargin{9};
                app.handles.isproofread=ones(app.handles.image_depth,1);
            else
                app.handles.isproofread=zeros(app.handles.image_depth,1);
                app.handles.neuronal_position=cell(app.handles.iend-app.handles.istart+1,1);
            end
            % Set up the parameters for the app
            app.TrackingLamp.Color = 'green';
            % Default low and high LUT
            app.handles.low=0;
            app.handles.high=200;

            % Default radius for searching moving neurons. Keep 0 for stationary
            app.handles.search_radius = 5;
            % Plot images
            img1=imagesc(app.UIAxes,app.handles.img_stack1(:,:,app.handles.istart),[app.handles.low app.handles.high]);
            set(img1,"HitTest","off");
            img2=imagesc(app.UIAxes_2,app.handles.img_stack2(:,:,app.handles.istart),[app.handles.low app.handles.high]);
            set(img2,"HitTest","off")
            colormap(app.UIAxes,gray);
            colormap(app.UIAxes_2,gray);
            title(app.UIAxes,app.handles.filename)

            app.handles.signal=[];
            app.handles.normalized_signal=[];
            app.handles.ratio=[];
            step=floor(app.handles.image_depth/10);
            app.Slider.MajorTicks = [0:step:app.handles.image_depth];
            app.Slider.Limits = [1 app.handles.image_depth];

            app.handles.frame_number=1;
            app.handles.signal_number=1;
        end

        % Menu selected function: LUTMenu
        function LUTMenuSelected(app, event)
            answer = inputdlg({'low','high'}, 'Cancel to clear previous', 1, ...
                {num2str(app.handles.low),num2str(app.handles.high)});
            app.handles.low=str2double(answer{1});
            app.handles.high=str2double(answer{2});
            hold(app.UIAxes,"on");
            hold(app.UIAxes_2,"on");
            img1=imagesc(app.UIAxes,app.handles.img_stack1(:,:,app.handles.frame_number+app.handles.istart-1),[app.handles.low app.handles.high]);
            set(img1,"HitTest","off");
            img2=imagesc(app.UIAxes_2,app.handles.img_stack2(:,:,app.handles.frame_number+app.handles.istart-1),[app.handles.low app.handles.high]);
            set(img2,"HitTest","off");
            hold(app.UIAxes,"off");
            hold(app.UIAxes_2,"off");
            colormap(app.UIAxes,gray);
            colormap(app.UIAxes_2,gray);

        end

        % Menu selected function: TresholdMenu
        function TresholdMenuSelected(app, event)
            answer = inputdlg({'tracking threshold (% of max)'}, 'Cancel to clear previous', 1, ...
                {num2str(app.handles.tracking_threshold)});

            app.handles.tracking_threshold=str2double(answer{1});
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = floor(app.Slider.Value);
            app.handles.frame_number = app.Slider.Value;
            app.Spinner.Value = app.Slider.Value;
            hold(app.UIAxes,"on");
            hold(app.UIAxes_2,"on");
            img1=imagesc(app.UIAxes,app.handles.img_stack1(:,:,app.handles.frame_number+app.handles.istart-1),[app.handles.low app.handles.high]);
            set(img1,"HitTest","off");
            img2=imagesc(app.UIAxes_2,app.handles.img_stack2(:,:,app.handles.frame_number+app.handles.istart-1),[app.handles.low app.handles.high]);
            set(img2,"HitTest","off");
            hold(app.UIAxes,"off");
            hold(app.UIAxes_2,"off");
            colormap(app.UIAxes,gray);
            colormap(app.UIAxes_2,gray);

            if ~isempty(app.handles.neuronal_position{app.handles.frame_number,1})
                x=app.handles.neuronal_position{app.handles.frame_number,1}(1);
                y=app.handles.neuronal_position{app.handles.frame_number,1}(2);
                hold(app.UIAxes,"on");
                app.handles.points1{1}=rectangle(app.UIAxes,'Curvature',[1,1],'Position',[x-app.handles.r(1), y-app.handles.r(1),...
                    2*app.handles.r(1), 2*app.handles.r(1)],'EdgeColor',app.handles.colorset(1,:));
                app.handles.points2{1}=rectangle(app.UIAxes_2,'Curvature',[1,1],'Position',[x-app.handles.r(1)+app.handles.x_shift, y-app.handles.r(1)+app.handles.y_shift,...
                    2*app.handles.r(1), 2*app.handles.r(1)],'EdgeColor',app.handles.colorset(1,:));
                hold(app.UIAxes,"off");
            
            end
            
            title(app.UIAxes_2,app.handles.frame_number)

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.handles.frame_number = app.Spinner.Value;
            app.Slider.Value = app.Spinner.Value;
            hold(app.UIAxes,"on");
            hold(app.UIAxes_2,"on");
            img1=imagesc(app.UIAxes,app.handles.img_stack1(:,:,app.handles.frame_number+app.handles.istart-1),[app.handles.low app.handles.high]);
            set(img1,"HitTest","off");
            img2=imagesc(app.UIAxes_2,app.handles.img_stack2(:,:,app.handles.frame_number+app.handles.istart-1),[app.handles.low app.handles.high]);
            set(img2,"HitTest","off");
            hold(app.UIAxes,"off");
            hold(app.UIAxes_2,"off");
            colormap(app.UIAxes,gray);
            colormap(app.UIAxes_2,gray);

            if ~isempty(app.handles.neuronal_position{app.handles.frame_number,1})
                x=app.handles.neuronal_position{app.handles.frame_number,1}(1);
                y=app.handles.neuronal_position{app.handles.frame_number,1}(2);
                hold(app.UIAxes,"on");
                app.handles.points1{1}=rectangle(app.UIAxes,'Curvature',[1,1],'Position',[x-app.handles.r(1), y-app.handles.r(1),...
                    2*app.handles.r(1), 2*app.handles.r(1)],'EdgeColor',app.handles.colorset(1,:));
                app.handles.points2{1}=rectangle(app.UIAxes_2,'Curvature',[1,1],'Position',[x-app.handles.r(1)+app.handles.x_shift, y-app.handles.r(1)+app.handles.y_shift,...
                    2*app.handles.r(1), 2*app.handles.r(1)],'EdgeColor',app.handles.colorset(1,:));
                hold(app.UIAxes,"off");

            end
            
            title(app.UIAxes_2,app.handles.frame_number)
        end

        % Menu selected function: SearchMenu
        function SearchMenuSelected(app, event)
            answer = inputdlg({'search radius'}, 'Cancel to clear previous', 1, {num2str(app.handles.search_radius)});

            app.handles.search_radius=str2double(answer{1});
        end

        % Menu selected function: TrackingMenu
        function TrackingMenuSelected(app, event)
            % Draw ROI and track neuron position for the first frame if it hasn't been done or
            % you at the first frame
            app.InstructionTextArea.Value = 'drag a circle on the left image';
            if isempty(app.handles.neuronal_position{1,1})||(app.handles.frame_number==1)

                app.TrackingLamp.Color = 'red';

                app.roi = drawcircle(app.UIAxes);

                app.handles.neuronal_position{1,1}=app.roi.Center;
                %                     handles.r(j)=max(l,w)/2;
                app.handles.r(1)=app.roi.Radius;
                hold(app.UIAxes,"on");
                app.handles.points1{1}=rectangle(app.UIAxes,'Curvature',[1,1],'Position',[app.roi.Center(1)-app.handles.r(1), app.roi.Center(2)-app.handles.r(1),...
                    2*app.handles.r(1), 2*app.handles.r(1)],'EdgeColor',app.handles.colorset(1,:));
                app.handles.points2{1}=rectangle(app.UIAxes_2,'Curvature',[1,1],'Position',[app.roi.Center(1)-app.handles.r(1), app.roi.Center(2)-app.handles.r(1),...
                    2*app.handles.r(1), 2*app.handles.r(1)],'EdgeColor',app.handles.colorset(1,:));
                hold(app.UIAxes,"off");
                %                     set(app.UIAxes,'ButtonDownFcn',UIAxesButtonDown(app,event));
                delete(app.roi)

                app.TrackingLamp.Color = 'green';

                %                 if app.handles.search_radius ~=0
                %                     app.handles.search_radius = mean(app.handles.r);
                %                 end
                
            end

            %             iend=min(handles.image_depth,handles.frame_number+2000);

            % Track neuron positions for the rest of frames.
            iend = app.handles.image_depth;
            for j=app.handles.frame_number+1:iend
                app.TrackingLamp.Color = 'red';
                if ~app.handles.isproofread(j)
                    
                        if isempty(app.handles.transformation_array)
                            app.handles.neuronal_position{j,1}=UpdateNeuronPosition(app,app.handles.img_stack1(:,:,j+app.handles.istart-1),...
                                app.handles.neuronal_position{j-1,1},2*app.handles.search_radius,app.handles.tracking_threshold);
                        else
                            shift=app.handles.transformation_array{j-1+app.handles.istart-1,2};
                            u=app.handles.neuronal_position{j-1,1}(1)-shift(1);
                            v=app.handles.neuronal_position{j-1,1}(2)-shift(2);
                            T=app.handles.transformation_array{j+app.handles.istart-1,1};
                            [xm,ym] = tformfwd(T,u,v);
                            app.handles.neuronal_position{j,1}(1)=xm;
                            app.handles.neuronal_position{j,1}(2)=ym;
                            shift_new=app.handles.transformation_array{j,2};
                            app.handles.neuronal_position{j,1}=app.handles.neuronal_position{j,1}+shift_new(1:2);
                        end
                    
                else
                    break;
                end
                app.TrackingLamp.Color = 'green';
            end

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
%             app.handles.isproofread(app.handles.frame_number)=1;
            %             [x,y]=event.IntersectionPoint;
            x=event.IntersectionPoint(1);
            y=event.IntersectionPoint(2);
            d_min=app.handles.image_height;

            
            distance=norm(app.handles.neuronal_position{app.handles.frame_number,1}-[x y]);
            if distance<d_min
                d_min=distance;
                idx=1;
            end


            app.handles.neuronal_position{app.handles.frame_number,idx}(1)=x;
            app.handles.neuronal_position{app.handles.frame_number,idx}(2)=y;

            if ~isempty(app.handles.points1{idx})
                delete(app.handles.points1{idx});
                delete(app.handles.points2{idx});
            end
            hold(app.UIAxes,"on");
            app.handles.points1{idx}=rectangle(app.UIAxes,'Curvature',[1,1],'Position',[x-app.handles.r(idx), y-app.handles.r(idx),...
                2*app.handles.r(idx), 2*app.handles.r(idx)],'EdgeColor',app.handles.colorset(idx,:));
            app.handles.points2{idx}=rectangle(app.UIAxes_2,'Curvature',[1,1],'Position',[x-app.handles.r(idx)+app.handles.x_shift, y-app.handles.r(idx)+app.handles.y_shift,...
                2*app.handles.r(idx), 2*app.handles.r(idx)],'EdgeColor',app.handles.colorset(idx,:));
            hold(app.UIAxes,"off");
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
%             app.handles.isproofread(app.handles.frame_number)=1;
            %             [x,y]=event.IntersectionPoint;
            x=event.IntersectionPoint(1);
            y=event.IntersectionPoint(2);
            d_min=app.handles.image_height;

            distance=norm(app.handles.neuronal_position{app.handles.frame_number,1}-[x y]);
            if distance<d_min
                d_min=distance;
                idx=1;
            end
            
            app.handles.x_shift = x-app.handles.neuronal_position{app.handles.frame_number,idx}(1);
            app.handles.y_shift = y-app.handles.neuronal_position{app.handles.frame_number,idx}(2);
            %             app.handles.neuronal_position{app.handles.frame_number,idx}(1)=x;
            %             app.handles.neuronal_position{app.handles.frame_number,idx}(2)=y;

            if ~isempty(app.handles.points1{idx})
                delete(app.handles.points2{idx});
            end
            hold(app.UIAxes_2,"on");
            app.handles.points2{idx}=rectangle(app.UIAxes_2,'Curvature',[1,1],'Position',[x-app.handles.r(idx), y-app.handles.r(idx),...
                2*app.handles.r(idx), 2*app.handles.r(idx)],'EdgeColor',app.handles.colorset(idx,:));
            hold(app.UIAxes_2,"off");
        end

        % Menu selected function: TotalIntensityredMenu
        function TotalIntensityredMenuSelected(app, event)
            if ~isempty(app.handles.neuronal_position{app.handles.iend,1})

                GC=zeros(app.handles.iend,1);
                for j=1:app.handles.iend
                    x=app.handles.neuronal_position{j,1}(1);
                    y=app.handles.neuronal_position{j,1}(2);
                    c_mask=CircleMask(app,app.handles.image_width,app.handles.image_height,x,y,app.handles.r(1));
                    img=app.handles.img_stack1(:,:,j+app.handles.istart-1);
                    img_bw=img(c_mask);
                    GC(j)=CalculateIntensity(app,img_bw);
                end
                app.handles.total_intensity{1}=GC;


            end
            if ~isempty(app.handles.total_intensity)
                figure(999);
                hold("on");
                plot(app.handles.image_times,app.handles.total_intensity{1});
                title('Red')
                assignin("base","Red",app.handles.total_intensity{1})
            end


        end

        % Menu selected function: TotalIntensityGCaMPMenu
        function TotalIntensityGCaMPMenuSelected(app, event)
            if ~isempty(app.handles.neuronal_position{app.handles.iend,1})

                GC=zeros(app.handles.iend,1);
                for j=1:app.handles.iend
                    x=app.handles.neuronal_position{j,1}(1)+app.handles.x_shift;
                    y=app.handles.neuronal_position{j,1}(2)+app.handles.y_shift;
                    c_mask=CircleMask(app,app.handles.image_width,app.handles.image_height,x,y,app.handles.r(1));
                    img=app.handles.img_stack2(:,:,j+app.handles.istart-1);
                    img_bw=img(c_mask);
                    GC(j)=CalculateIntensity(app,img_bw);
                end
                % for x=2:length(GC)-1
                %     if GC(x)<0.47*(GC(x-1)+GC(x+1))
                %         GC(x)=0.5*(GC(x-1)+GC(x+1));
                %     end
                % end
                app.handles.total_intensity{1}=GC;

            end
            if ~isempty(app.handles.total_intensity)
                figure(1000);
                hold on


                %                     plot(app.handles.image_times,app.handles.total_intensity{k},'Color',color(k,:));
                plot(app.handles.total_intensity{1},'Color', app.handles.colorset(app.handles.neuron_number,:));


                title('GCaMP Total')
                assignin("base","signal",app.handles.total_intensity{1})
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [fn, savepathname]= uiputfile('*.mat', 'choose file to save', strcat(app.handles.filename, '_',num2str(app.handles.istart),'-',num2str(app.handles.iend),'.mat'));
            if length(fn) > 1
                fnamemat = strcat(savepathname,fn);
                save(fnamemat);
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            delete(uifigure)
        end

        % Menu selected function: RatiouseaftertotalMenu
        function RatiouseaftertotalMenuSelected(app, event)

            if ~isempty(app.handles.total_intensity)
                
                figure(1001);
                hold on
                ratio = (app.handles.total_intensity{1}-min(app.handles.total_intensity{1}))/(min(app.handles.total_intensity{1}));
                plot(app.handles.image_times,ratio,'Color', app.handles.colorset(app.handles.neuron_number,:));
                figure(1010);
                hold on
                x=app.handles.neuronal_position{1,1}(1)+app.handles.x_shift;
                y=app.handles.neuronal_position{1,1}(2)+app.handles.y_shift;
                rectangle('Curvature',[1,1],'Position',[x-app.handles.r(1),y-app.handles.r(1), 2*app.handles.r(1), 2*app.handles.r(1)],...
                    'EdgeColor',app.handles.colorset(app.handles.neuron_number,:));
                hold off
                app.handles.neuron_number = app.handles.neuron_number+1;
%                     plot(ratio);
                title('GCaMP Ratio')
            end
        end

        % Menu selected function: SignalMenu
        function SignalMenuSelected(app, event)
            figure;
            img=app.handles.img_stack1(:,:,app.handles.frame_number);
            imagescale = img-app.handles.low;
            imagescale=imagescale*(255/app.handles.high);
            se = strel('disk',1);
            Ie = imerode(imagescale,se);
            Iobr = imreconstruct(Ie,imagescale);
            Iobrd = imdilate(Iobr,se);
            fgm = imregionalmax(Iobrd);
            cc = bwpropfilt(fgm,'MajorAxisLength',[3 2*app.handles.r(1)],8);
            imshowpair(img,cc)
            figure;
            imshowpair(img,fgm)
        end

        % Menu selected function: Signal_temperatureMenu
        function Signal_temperatureMenuSelected(app, event)
            figure(1002);
            hold on
            [~,app.signal.name]=fileparts(app.signal.filename);
            
            
            temperature=h5read(char(app.signal.filename),'/temperature1_real');
            temperature(temperature<10)=NaN;
%             time=h5read(char(app.signal.filename),'/time');
%             time = time-time(1);
            fig_temp=subplot(2,1,1);
            
            plot(fig_temp,app.handles.image_times,temperature)
            set(fig_temp,'InnerPosition',[0.07,0.75,0.9,0.17],'XLim',[0 app.handles.image_times(end)],'Box',"off",'FontSize',16,'XTickLabel',[],...
                'YTick',round(min(temperature)):1:round(max(temperature)),'YGrid',"on",'XTick',[],'XGrid',"on");
            title(fig_temp,[app.signal.name,'   ',app.handles.filename],'Interpreter',"none",'FontSize',18)
            fig_signal = subplot(2,1,2);
            hold on


            ratio = (app.handles.total_intensity{1}-min(app.handles.total_intensity{1}))/(min(app.handles.total_intensity{1}));
            plot(fig_signal,app.handles.image_times,ratio,"LineWidth",2);


            set(fig_signal,'InnerPosition',[0.07,0.15,0.9,0.60],'XLim',[0 app.handles.image_times(end)],'FontSize',16,...
                'XTick',[],'XGrid',"on");
            button = 1;
            while sum(button) <=1   % read ginputs until a mouse right-button occurs
                [x,~,button] = ginput(1);
                set(fig_temp,'XTick',round(sort([xticks,x])));
                set(fig_signal,'XTick',xticks);
            end


        end

        % Value changed function: InstructionTextArea
        function InstructionTextAreaValueChanged(app, event)
            value = app.InstructionTextArea.Value;
            
        end

        % Button pushed function: ReferenceSignalButton
        function ReferenceSignalButtonPushed(app, event)
            assignin("base","Reference",app.handles.total_intensity{1})
        end

        % Menu selected function: MakeStableMovieMenu
        function MakeStableMovieMenuSelected(app, event)
            app.InstructionTextArea.Value = 'drag a rectangle on the left image around the neurons you want to make a video of';
            ROI = drawrectangle(app.UIAxes);
            inset = app.handles.img_stack2(round(ROI.Position(2)):round(ROI.Position(2)+ROI.Position(4)),...
                round(ROI.Position(1)):round(ROI.Position(1)+ROI.Position(3)),1);            
            inset_stack=uint8(zeros(size(inset)));
            inset_height = [round(ROI.Position(2)) round(ROI.Position(2)+ROI.Position(4))];
            inset_width = [round(ROI.Position(1)) round(ROI.Position(1)+ROI.Position(3))];
            height_shift = app.handles.neuronal_position{1}(2)-inset_height;
            width_shift = app.handles.neuronal_position{1}(1)-inset_width;
            XL_image = uint8(zeros(app.handles.image_height*3,app.handles.image_width*3));
            warning('off','all')
            for i=app.handles.istart+1:app.handles.iend
                XL_image(app.handles.image_height+1:app.handles.image_height*2,app.handles.image_width+1:app.handles.image_width*2) = app.handles.img_stack2(:,:,i);
%                 inset_width = [round(app.handles.neuronal_position{i}(1))-round(ROI.Position(3)/2);round(app.handles.neuronal_position{i}(1))-round(ROI.Position(3)/2)+size(inset,2)-1];
%                 inset_height = [round(app.handles.neuronal_position{i}(2))-round(ROI.Position(4)/2);round(app.handles.neuronal_position{i}(2))-round(ROI.Position(4)/2)+size(inset,1)-1];
%                 inset_width = inset_width+round(app.handles.neuronal_position{i}(1)-app.handles.neuronal_position{i-1}(1));
%                 inset_height = inset_height+round(app.handles.neuronal_position{i}(2)-app.handles.neuronal_position{i-1}(2));
                inset_width(i,:) = app.handles.neuronal_position{i}(1)-width_shift;
                inset_height(i,:) = app.handles.neuronal_position{i}(2)-height_shift;
                
                inset = XL_image(app.handles.image_height+inset_height(i,1):app.handles.image_height+inset_height(i,2),...
                    app.handles.image_width+inset_width(i,1):app.handles.image_width+inset_width(i,2),1);
                inset_stack(:,:,i)=inset;
            end
            warning('on','all')
            [~,app.signal.name]=fileparts(app.signal.filename);
            movie_params = inputdlg({'Enter movie name','Enter X Speed','Enter brightness multiplier'},'Movie inputs',[1 30],{app.signal.name,'40','4'});
            stack2movie(inset_stack,str2double(movie_params{2})*length(app.handles.image_times)/max(app.handles.image_times),...
                [movie_params{1},'_',movie_params{2},'_X',movie_params{3}],[1 size(inset_stack,1) 1 size(inset_stack,2)],str2double(movie_params{3}));
            inset_stack_double = double(inset_stack);
            stack_std = std(inset_stack_double,0,3);
            figure(1003);
            hold on
            imshow(stack_std/max(stack_std,[],'all'))
            % imshow(stack_std*movie_params{3}/max(stack_std,[],'all'))
            hold off
            assignin("base","inset_height",inset_height);
            assignin("base","inset_width",inset_width);
            % imshow(max(inset_stack,[],3)*movie_params{3})
        end

        % Button pushed function: SaveSignalButton
        function SaveSignalButtonPushed(app, event)
            app.handles.signal(:,app.handles.signal_number) = app.handles.total_intensity{1};
            assignin("base","signals",app.handles.signal)
            app.handles.signal_number = app.handles.signal_number+1;
            

        end

        % Button pushed function: NeuronposButton
        function NeuronposButtonPushed(app, event)
            app.TrackingLamp.Color = 'red';
            % info = evalin('base', 'info');
            filename = evalin('base', 'filename');
            frames_num=double(h5readatt(filename,'/','T'));
            NeurPos = cell2mat(app.handles.neuronal_position);
            NeurPos = floor([NeurPos(:,1)+app.handles.x_shift,NeurPos(:,2)+app.handles.y_shift]);
            NeurArea = [NeurPos(:,1)-floor(app.handles.r),NeurPos(:,1)+floor(app.handles.r),NeurPos(:,2)-floor(app.handles.r),NeurPos(:,2)+floor(app.handles.r)];
            % x=floor(app.handles.neuronal_position{1,1}(1)+app.handles.x_shift);
            % y=floor(app.handles.neuronal_position{1,1}(2)+app.handles.y_shift);
            app.TrackingLamp.Color = 'red';
            for i = 0:frames_num-1
                h5=h5read(filename,['/' int2str(i) '/frame']);
                neuron_intensity_stack = double(h5(:,NeurArea(i+1,3):NeurArea(i+1,4),NeurArea(i+1,1):NeurArea(i+1,2),2));
                neuron_intensity_stack_Z(:,i+1)=max(neuron_intensity_stack,[],[2,3]);
            end
            app.TrackingLamp.Color = 'green';
            figure;
            imshow(uint8(neuron_intensity_stack_Z*300/(max(neuron_intensity_stack_Z,[],'all'))));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.PlotMenu_2 = uimenu(app.Menu);
            app.PlotMenu_2.Text = 'Plot';
            app.TotalIntensityGCaMPMenu = uimenu(app.PlotMenu_2);
            app.TotalIntensityGCaMPMenu.MenuSelectedFcn = createCallbackFcn(app, @TotalIntensityGCaMPMenuSelected, true);
            app.TotalIntensityGCaMPMenu.Text = 'Total Intensity GCaMP';
            app.RatiouseaftertotalMenu = uimenu(app.PlotMenu_2);
            app.RatiouseaftertotalMenu.MenuSelectedFcn = createCallbackFcn(app, @RatiouseaftertotalMenuSelected, true);
            app.RatiouseaftertotalMenu.Text = 'Ratio (use after total)';
            app.Signal_temperatureMenu = uimenu(app.PlotMenu_2);
            app.Signal_temperatureMenu.MenuSelectedFcn = createCallbackFcn(app, @Signal_temperatureMenuSelected, true);
            app.Signal_temperatureMenu.Text = 'Signal_temperature';
            app.MakeStableMovieMenu = uimenu(app.Menu);
            app.MakeStableMovieMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeStableMovieMenuSelected, true);
            app.MakeStableMovieMenu.Text = 'Make Stable Movie';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.TrackingLampLabel = uilabel(app.UIFigure);
            app.TrackingLampLabel.Text = 'Tracking';
            app.TrackingLamp = uilamp(app.UIFigure);
            app.InstructionTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionTextAreaLabel.Text = 'Instruction';
            app.InstructionTextArea = uitextarea(app.UIFigure);
            app.InstructionTextArea.ValueChangedFcn = createCallbackFcn(app, @InstructionTextAreaValueChanged, true);
            app.ReferenceSignalButton = uibutton(app.UIFigure, 'push');
            app.ReferenceSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceSignalButtonPushed, true);
            app.ReferenceSignalButton.Text = {'Reference'; 'Signal'};
            app.SaveSignalButton = uibutton(app.UIFigure, 'push');
            app.SaveSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSignalButtonPushed, true);
            app.SaveSignalButton.Text = 'Save Signal';
            app.NeuronposButton = uibutton(app.UIFigure, 'push');
            app.NeuronposButton.ButtonPushedFcn = createCallbackFcn(app, @NeuronposButtonPushed, true);
            app.NeuronposButton.Text = 'Neuron pos';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.FileMenu = uimenu(app.ContextMenu);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.TrackMenu = uimenu(app.ContextMenu);
            app.TrackMenu.Text = 'Track';
            app.LUTMenu = uimenu(app.TrackMenu);
            app.LUTMenu.MenuSelectedFcn = createCallbackFcn(app, @LUTMenuSelected, true);
            app.LUTMenu.Text = 'LUT';
            app.TrackingMenu = uimenu(app.TrackMenu);
            app.TrackingMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackingMenuSelected, true);
            app.TrackingMenu.Text = 'Tracking';
            app.SearchMenu = uimenu(app.TrackMenu);
            app.SearchMenu.MenuSelectedFcn = createCallbackFcn(app, @SearchMenuSelected, true);
            app.SearchMenu.Text = 'Search';
            app.TresholdMenu = uimenu(app.TrackMenu);
            app.TresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @TresholdMenuSelected, true);
            app.TresholdMenu.Text = 'Treshold';
            app.PlotMenu = uimenu(app.ContextMenu);
            app.PlotMenu.Text = 'Plot';
            app.SignalMenu = uimenu(app.PlotMenu);
            app.SignalMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalMenuSelected, true);
            app.SignalMenu.Text = 'Signal';
            app.TotalIntensityredMenu = uimenu(app.PlotMenu);
            app.TotalIntensityredMenu.MenuSelectedFcn = createCallbackFcn(app, @TotalIntensityredMenuSelected, true);
            app.TotalIntensityredMenu.Text = 'Total Intensity red';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calcium_Stan_Single_Neuron(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end